classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisizioneDatiTab            matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        SelezioneGestoDropDown         matlab.ui.control.DropDown
        SelezioneGestoDropDownLabel    matlab.ui.control.Label
        SelezioneManoDropDown          matlab.ui.control.DropDown
        SelezioneManoDropDownLabel     matlab.ui.control.Label
        SceglieretraleopzionidisponibiliLabel_2  matlab.ui.control.Label
        SceglieretraleopzionidisponibiliLabel  matlab.ui.control.Label
        CampoobbligatorioLabel_2       matlab.ui.control.Label
        CampoobbligatorioLabel         matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        StopSaveButton                 matlab.ui.control.Button
        StartAcquisitionButton         matlab.ui.control.Button
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        SmartphoneModelEditField       matlab.ui.control.EditField
        SmartphoneModelEditFieldLabel  matlab.ui.control.Label
        PersonIDEditField              matlab.ui.control.EditField
        PersonIDEditFieldLabel         matlab.ui.control.Label
        VisualizzazioneDatiTab         matlab.ui.container.Tab
        EstrazioneFeatureTab           matlab.ui.container.Tab
        ClassificazioneGestiTab        matlab.ui.container.Tab
    end

    properties (Access = private)
        smartphone                      % Oggetto mobiledev
        signalsStruct = struct()        % Struttura dati
        currentGesture = 1              % Indice gesto corrente
        isAcquiring = false             % Flag acquisizione
        currentRep = 1                  % Ripetizione corrente
        availableSensorsStr             % Sensori disponibili
        allGestures                     % Lista completa dei gesti
        acquisitionInterrupted = false  % Flag interruzione
        globalAcquisitionCounter = 1    % Contatore globale
        startFromGesture = 1            % Indice gesto iniziale
        waitForNext = false             % Next per bloccare le acquisizioniù
    end
    
    methods (Access = private)

        function availableSensorsStr = getAvailableSensors(app)
            sensors = [];
            if app.smartphone.AccelerationSensorEnabled, sensors = [sensors, 1]; end
            if app.smartphone.AngularVelocitySensorEnabled, sensors = [sensors, 2]; end
            if app.smartphone.MagneticSensorEnabled, sensors = [sensors, 3]; end
            if app.smartphone.OrientationSensorEnabled, sensors = [sensors, 4]; end
    
            % Converti i numeri in cell array di caratteri
            sensorCells = arrayfun(@num2str, sensors, 'UniformOutput', false);
    
            % Unisci con il separatore '-'
            availableSensorsStr = strjoin(sensorCells, '-');
            app.StatusTextArea.Value = [app.StatusTextArea.Value; 
            {sprintf('Sensori disponibili: %s', availableSensorsStr)}];
        end
        
        function initializeAcquisition(app)
            % Elimina inizializzazione smartphone se già presente
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                delete(app.smartphone);
                app.smartphone = [];
            end
            % Inizializza dispositivo mobile
            app.smartphone = mobiledev;
            
            % Configurazione sensori
            app.smartphone.AccelerationSensorEnabled = 1;
            app.smartphone.MagneticSensorEnabled = 1;
            app.smartphone.OrientationSensorEnabled = 1;
            app.smartphone.AngularVelocitySensorEnabled = 1;
            app.smartphone.SampleRate = 100;
            
            % Carica/Crea struttura dati
            if exist('signalsStructFile.mat', 'file')
                loadedData = load('signalsStructFile.mat');
                app.signalsStruct = loadedData.signalsStructFile;
            else
                app.signalsStruct = struct();
            end
            
            % Inizializza lista gesti
            app.allGestures = [arrayfun(@(x) sprintf('Class %d', x), 0:23, 'UniformOutput', false), {'Class 24'}];
        end

        function startGestureAcquisition(app)
            % Resetta i contatori all'inizio
            app.globalAcquisitionCounter = 1;
            app.startFromGesture = find(strcmp(app.SelezioneGestoDropDown.Value, app.allGestures), 1);
            
            % Verifica esistenza soggetto nella struttura
            if ~isfield(app.signalsStruct, app.PersonIDEditField.Value)
                app.signalsStruct.(app.PersonIDEditField.Value) = struct();
            end

            for gestureIdx = app.startFromGesture:length(app.allGestures)
                % Calcola l'indice progressivo corretto
                gestureID = app.allGestures{gestureIdx};
                baseIndex = (gestureIdx - app.startFromGesture) * 5;
                for rep = 1:5
                    if ~app.isAcquiring, return; end
                    
                    % Calcola nome acquisizione
                    acqNumber = baseIndex + rep;
                    acqName = sprintf('acquisition_%d', acqNumber);

                    % Aggiornamento UI
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                        {sprintf('Gesto: %s - Ripetizione: %d/5', gestureID, rep)}];
                    drawnow;
                    
                    % Attiva attesa
                    app.waitForNext = true;
                    app.NextButton.Enable = 'on';
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                        {'Premere "Next" per iniziare l''acquisizione'}];
                
                    % Attesa asincrona
                    while isvalid(app) && app.waitForNext && app.isAcquiring
                        pause(0.1);
                        drawnow;
                    end
                
                    if ~app.isAcquiring, return; end

                    % Acquisizione dati
                    app.acquireData(gestureIdx, rep);
                
                    if app.acquisitionInterrupted
                        return;
                    end

                end
                % Chiedi conferma per proseguire
                if gestureIdx ~= length(app.allGestures) || rep ~= 5
                    choice = questdlg('Continuare con il prossimo gesto?', ...
                        'Conferma', 'Si', 'No', 'Si');
                    if strcmp(choice, 'No')
                        app.saveData();
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                            {'Acquisizioni completate per questo gesto!'}];
                        app.isAcquiring = false;
                        return;
                    end
                end
            end
 
            app.saveData();
            app.StatusTextArea.Value = [app.StatusTextArea.Value; 
                {'Acquisizioni completate per tutti i gesti!'}];
            app.isAcquiring = false;
        end

        function acquireData(app, gestureIdx, ~)
            try
                gestureID = app.allGestures{gestureIdx};
                % Avvia acquisizione
                app.smartphone.Logging = 1;
                pause(3.5); % Pausa acquisizione
                app.smartphone.Logging = 0;
                
                % Raccolta dati
                [acc, ~] = accellog(app.smartphone);
                [gyro, ~] = angvellog(app.smartphone);
                [mag, ~] = magfieldlog(app.smartphone);
                [orientation, ~] = orientlog(app.smartphone);
                app.smartphone.discardlogs;
                
                % Verifica dati
                if any([size(acc,1), size(gyro,1), size(mag,1), size(orientation,1)] < 250)
                    error('Acquisizione troppo corta!');
                end
                
                % Elaborazione dati
                acc = acc(1:250,:);
                gyro = gyro(1:250,:);
                mag = mag(1:250,:);
                orientation = orientation(1:250,:);
                
                % Salvataggio nella struttura
                acqName = sprintf('acquisition_%d', app.globalAcquisitionCounter);
                if ~isfield(app.signalsStruct, app.PersonIDEditField.Value)
                    app.signalsStruct.(app.PersonIDEditField.Value) = struct();
                end
                
                app.signalsStruct.(app.PersonIDEditField.Value).(acqName) = struct(...
                    'acc', acc, ...
                    'gyro', gyro, ...
                    'mag', mag, ...
                    'orientation', orientation, ...
                    'GestureID', gestureID, ...
                    'Hand', app.SelezioneManoDropDown.Value, ...
                    'SmartphoneModel', app.SmartphoneModelEditField.Value, ...
                    'AvailableSensors', app.availableSensorsStr);
                
                % Incrementa contatore globale
                app.globalAcquisitionCounter = app.globalAcquisitionCounter + 1;
                
            catch ME
                app.StatusTextArea.Value = [app.StatusTextArea.Value; {ME.message}];
                app.isAcquiring = false;
            end
        end

        function saveData(app)
            % Salva la struttura dati in un file MAT
            signalsStructFile = app.signalsStruct;
            save('signalsStructFile.mat', 'signalsStructFile');

            % Prepara la raccolta dei metadati in formato tabellare
            subjects = fieldnames(signalsStructFile);
    
            % Inizializza le variabili per ciascuna colonna
            ID_Subject       = {};
            Idx_Acquisition  = [];
            Hand             = {};
            SmartphoneModel  = {};
            AvailableSensors = {};
            ID_Gesture       = {};
    
            % Ciclo per ogni soggetto e per ciascuna acquisizione
            for i = 1:numel(subjects)
                person = subjects{i};
                acquisitions = fieldnames(signalsStructFile.(person));
                for j = 1:numel(acquisitions)
                    acq = signalsStructFile.(person).(acquisitions{j});
                    ID_Subject{end+1,1}       = person;
                    Idx_Acquisition(end+1,1)  = j;
                    Hand{end+1,1}             = acq.Hand;
                    SmartphoneModel{end+1,1}  = acq.SmartphoneModel;
                    AvailableSensors{end+1,1} = acq.AvailableSensors;
                    ID_Gesture{end+1,1}       = acq.GestureID;
                end
            end

            % Creazione della tabella dei metadati
            metadataTable = table(ID_Subject, Idx_Acquisition, Hand, SmartphoneModel, ...
                                AvailableSensors, ID_Gesture);
    
            % Salva la tabella in un file CSV SENZA la prima riga dei label
            writetable(metadataTable, 'Metadati.csv', 'WriteVariableNames', false);
    
            % Aggiorna lo status
            app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Dati salvati correttamente!'}];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NextButton.Enable = 'off';
            app.StopSaveButton.Enable = "off";
        end

        % Button pushed function: StartAcquisitionButton
        function StartAcquisitionButtonPushed(app, event)
            app.StatusTextArea.Value = {''};
            app.StartAcquisitionButton.Enable = 'off';  % Disabilita il tasto Start
            app.StopSaveButton.Enable = 'on';           % Abilita il tasto Stop

            % Verifica input utente
            if isempty(app.PersonIDEditField.Value) || isempty(app.SmartphoneModelEditField.Value)
                app.StatusTextArea.Value = 'Compilare tutti i campi obbligatori!';
                if ~isempty(app.smartphone) && isvalid(app.smartphone)
                    clear app.smartphone;
                end
                app.isAcquiring = false;
                return;
            end

            % Carica i dati esistenti
            if exist('signalsStructFile.mat', 'file')
                loadedData = load('signalsStructFile.mat');
                app.signalsStruct = loadedData.signalsStructFile;
            end

            % Verifica se l'ID è già presente
            while isfield(app.signalsStruct, app.PersonIDEditField.Value)
                app.StatusTextArea.Value = {'ID già in uso! Cambiare il campo Person ID'};
                pause(1); % Pausa per evitare blocchi
                newID = inputdlg('Inserisci un nuovo ID:', 'ID già in uso', [1 50]);
                if isempty(newID) % Se l'utente annulla
                    return;
                end
                app.PersonIDEditField.Value = newID{1};
            end

            app.StatusTextArea.Value = [app.StatusTextArea.Value;
                {sprintf('Attivazione sensori...')}];
            app.StopSaveButton.Enable = "on";
            % Resetta le variabili di stato
            app.isAcquiring = true;
            app.acquisitionInterrupted = false;
            app.globalAcquisitionCounter = 1; % Resetta contatore globale
            % Inizializza acquisizione
            try
                app.initializeAcquisition();
                app.availableSensorsStr = app.getAvailableSensors();
            catch ME
                app.StatusTextArea.Value = {'Errore nell''inizializzazione del dispositivo! Riprova.'};
                return;
            end 
            % Avvia acquisizione dal gesto selezionato
            app.StatusTextArea.Value = [app.StatusTextArea.Value;
                {sprintf('Avvio acquisizione dal gesto selezionato...')}];
            drawnow;
            startGestureAcquisition(app);
        end

        % Button pushed function: StopSaveButton
        function StopSaveButtonPushed(app, event)
            app.StatusTextArea.Value = {''};
            app.isAcquiring = false;
            app.acquisitionInterrupted = true;
            app.saveData();
            app.StatusTextArea.Value = [app.StatusTextArea.Value; {'Acquisizione interrotta e dati salvati!'}];
            app.StartAcquisitionButton.Enable = 'on';  % Riabilita il tasto Start
            app.StopSaveButton.Enable = 'off';         % Disabilita il tasto Stop
            app.NextButton.Enable = 'off';
            % Elimina la connessione con il dispositivo per riavviare senza errori**
            if ~isempty(app.smartphone) && isvalid(app.smartphone)
                app.smartphone.Logging = 0; % Ensure logging is stopped
                delete(app.smartphone);
                app.smartphone = [];
            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.StatusTextArea.Value = {'Acquisizione in corso...'};
            app.waitForNext = false;
            app.NextButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisizioneDatiTab = uitab(app.TabGroup);
            app.AcquisizioneDatiTab.Title = 'Acquisizione Dati';
            app.GridLayout = uigridlayout(app.AcquisizioneDatiTab);
            app.PersonIDEditFieldLabel = uilabel(app.GridLayout);
            app.PersonIDEditFieldLabel.Text = 'Person ID';
            app.PersonIDEditField = uieditfield(app.GridLayout, 'text');
            app.PersonIDEditField.Placeholder = 'Es: P1 (P necessaria)';
            app.SmartphoneModelEditFieldLabel = uilabel(app.GridLayout);
            app.SmartphoneModelEditFieldLabel.Text = 'Smartphone Model';
            app.SmartphoneModelEditField = uieditfield(app.GridLayout, 'text');
            app.SmartphoneModelEditField.Placeholder = 'Es: "Samsung Galaxy S23"';
            app.StatusTextAreaLabel = uilabel(app.GridLayout);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.GridLayout);
            app.StatusTextArea.Placeholder = 'Qui appariranno informazioni sulle acquisizioni dei dati';
            app.StartAcquisitionButton = uibutton(app.GridLayout, 'push');
            app.StartAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @StartAcquisitionButtonPushed, true);
            app.StartAcquisitionButton.Text = 'Start Acquisition';
            app.StopSaveButton = uibutton(app.GridLayout, 'push');
            app.StopSaveButton.ButtonPushedFcn = createCallbackFcn(app, @StopSaveButtonPushed, true);
            app.StopSaveButton.Text = 'Stop & Save';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CampoobbligatorioLabel = uilabel(app.GridLayout);
            app.CampoobbligatorioLabel.Text = {'Campo'; 'obbligatorio'};
            app.CampoobbligatorioLabel_2 = uilabel(app.GridLayout);
            app.CampoobbligatorioLabel_2.Text = {'Campo'; 'obbligatorio'};
            app.SceglieretraleopzionidisponibiliLabel = uilabel(app.GridLayout);
            app.SceglieretraleopzionidisponibiliLabel.Text = {'Scegliere tra le '; 'opzioni disponibili'};
            app.SceglieretraleopzionidisponibiliLabel_2 = uilabel(app.GridLayout);
            app.SceglieretraleopzionidisponibiliLabel_2.Text = {'Scegliere tra le '; 'opzioni disponibili'};
            app.SelezioneManoDropDownLabel = uilabel(app.GridLayout);
            app.SelezioneManoDropDownLabel.Text = 'Selezione Mano';
            app.SelezioneManoDropDown = uidropdown(app.GridLayout);
            app.SelezioneManoDropDown.Items = {'dx', 'sx'};
            app.SelezioneManoDropDown.Value = 'dx';
            app.SelezioneGestoDropDownLabel = uilabel(app.GridLayout);
            app.SelezioneGestoDropDownLabel.Text = 'Selezione Gesto';
            app.SelezioneGestoDropDown = uidropdown(app.GridLayout);
            app.SelezioneGestoDropDown.Items = {'Class 0', 'Class 1', 'Class 2', 'Class 3', 'Class 4', 'Class 5', 'Class 6', 'Class 7', 'Class 8', 'Class 9', 'Class 10', 'Class 11', 'Class 12', 'Class 13', 'Class 14', 'Class 15', 'Class 16', 'Class 17', 'Class 18', 'Class 19', 'Class 20', 'Class 21', 'Class 22', 'Class 23', 'Class 24'};
            app.SelezioneGestoDropDown.Value = 'Class 0';
            app.VisualizzazioneDatiTab = uitab(app.TabGroup);
            app.VisualizzazioneDatiTab.Title = 'Visualizzazione Dati';
            app.EstrazioneFeatureTab = uitab(app.TabGroup);
            app.EstrazioneFeatureTab.Title = 'Estrazione Feature';
            app.ClassificazioneGestiTab = uitab(app.TabGroup);
            app.ClassificazioneGestiTab.Title = 'Classificazione Gesti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end